classdef CLA_DataDashboard_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DASHBOARDUIFigure        matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Image4                   matlab.ui.control.Image
        Panel_ChartRight         matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        UIAxes_ChartRight        matlab.ui.control.UIAxes
        Panel_ChartLeft          matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        UIAxes_ChartLeft         matlab.ui.control.UIAxes
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Panel_Right              matlab.ui.container.Panel
        GridLayout2_2            matlab.ui.container.GridLayout
        UIAxes_ChartBottomRight  matlab.ui.control.UIAxes
        Panel_Left               matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        Label_LeftPanelData7     matlab.ui.control.Label
        Label_LeftPanelData6     matlab.ui.control.Label
        Label_LeftPanelData5     matlab.ui.control.Label
        Label_LeftPanelData4     matlab.ui.control.Label
        Label_LeftPanelData3     matlab.ui.control.Label
        Label_LeftPanelData2     matlab.ui.control.Label
        Label_LeftPanelData1     matlab.ui.control.Label
        TextArea_left7           matlab.ui.control.TextArea
        TextArea_left6           matlab.ui.control.TextArea
        TextArea_left5           matlab.ui.control.TextArea
        TextArea_left4           matlab.ui.control.TextArea
        TextArea_left3           matlab.ui.control.TextArea
        TextArea_left2           matlab.ui.control.TextArea
        TextArea_left1           matlab.ui.control.TextArea
        TextArea_Subtitle        matlab.ui.control.TextArea
        Label_Title              matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function makeChart(app,chartinputs)
            %
            %
            %
            %
            %
            % -------------------------------------------------------------

            chtype = chartinputs.ChartType;
            switch chtype

                case 'Histogram'
                    % HISTOGRAM PLOT

                    % Get inputs
                    X = chartinputs.X;
                    xlab = chartinputs.xlab;
                    ylab = chartinputs.ylab;
                    ax = app.(chartinputs.ax);

                    % Plot histogram
                    histogram(ax,X)
                    xlabel(ax,xlab)
                    ylabel(ax,ylab)
                    title(ax,"")

                case 'Box'
                    % Get inputs
                    ax = app.(chartinputs.ax);
                    X = chartinputs.X;
                    if isempty(chartinputs.GrpData)
                        GrpData = ones(size(X));
                    else
                        GrpData = chartinputs.GrpData;
                    end
                    xlab = chartinputs.xlab;
                    ylab = chartinputs.ylab;
                    % box and whisker
                    boxchart(ax,GrpData,X,...
                        "LineWidth",2,...
                        "JitterOutliers","on",...
                        "MarkerStyle",".",...
                        "MarkerSize",1,...
                        "MarkerColor",[1 0 0])
                    tit = sprintf("Potential Outliers (in red) Defined " + ...
                        "as\nMore Than 1.5 IQR From the Top of the Box");
                    xlabel(ax,xlab,'FontSize',9)
                    ylabel(ax,ylab,'FontSize',9);
                    title(ax,tit,'FontSize',9)                    


                case 'lineplot'
                    % line plot

                case 'scattergram'
                    % scatter plot

            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %
            % Basic CLA dashboard
            %
            %
            % -------------------------------------------------------------
            
            % Get any optional arguments and set properties accordingly
            % Set the input parser
            p = inputParser;

            % FILENAME
            def = strcat("DshBrd",num2str(floor(now()*100000)));
            defmsg = sprintf(['Problem with the DashboardFilen input. the ' ...
                'DashboardTitle must be a string']);
            addParameter(p,'DashboardFilen',def,...
                @(x)...
                assert(or(ischar(x),isstring(x)),...
                defmsg)...
                );


            % DASHBOARD TITLE
            def = sprintf("CLA Dashboard Title");
            defmsg = sprintf(['Problem with the DashboardTitle input. the ' ...
                'DashboardTitle must be a string']);
            addParameter(p,'DashboardTitle',def,...
                @(x)...
                assert(or(ischar(x),isstring(x)),...
                defmsg)...
                );

            % DASHBOARD SUBTITLE
            def = sprintf("Subtitle text line 1.\nSubtitle text line 2 (should not be more than two lines).");
            defmsg = sprintf(['Problem with the DashboardSubTitle input. the ' ...
                'DashboardSubTitle must be a string']);
            addParameter(p,'DashboardSubTitle',def,...
                @(x)...
                assert(or(ischar(x),isstring(x)),...
                defmsg)...
                );

            % CHART LEFT TITLE
            def = sprintf("Left Hand Chart Title");
            defmsg = sprintf(['Problem with the ChartLeftTitle input. the ' ...
                'ChartLeftTitle must be a string']);
            addParameter(p,'ChartLeftTitle',def,...
                @(x)...
                assert(or(ischar(x),isstring(x)),...
                defmsg)...
                );

            % CHART RIGHT TITLE
            def = sprintf("Right Hand Chart Title");
            defmsg = sprintf(['Problem with the ChartRightTitle input. the ' ...
                'ChartRightTitle must be a string']);
            addParameter(p,'ChartRightTitle',def,...
                @(x)...
                assert(or(ischar(x),isstring(x)),...
                defmsg)...
                );        

            % NOTES LEFT TITLE
            def = sprintf("Left Hand Notes Title");
            defmsg = sprintf(['Problem with the NotesLeftTitle input. the ' ...
                'ChartLeftTitle must be a string']);
            addParameter(p,'NotesLeftTitle',def,...
                @(x)...
                assert(or(ischar(x),isstring(x)),...
                defmsg)...
                ); 


            % NOTES LEFT DATA STRUCTURE
            def = struct();
            def.titles.title1 = sprintf('Title1');
            def.titles.title2 = sprintf('Title2');
            def.titles.title3 = sprintf('Title3');
            def.titles.title4 = sprintf('Title4');
            def.titles.title5 = sprintf('Title5');
            def.titles.title6 = sprintf('Title6');
            def.titles.title7 = sprintf('Title7');           
            def.data.data1 = num2str(1);
            def.data.data2 = num2str(2);
            def.data.data3 = num2str(3);
            def.data.data4 = num2str(4);
            def.data.data5 = num2str(5);
            def.data.data6 = num2str(6);
            def.data.data7 = num2str(7);
            defmsg = sprintf(['Problem with the NotesLeftData input. the ' ...
                'NotesLeftData must be a structure']);
            addParameter(p,'NotesLeftData',def,...
                @(x)...
                assert(or(isstruct(x),isempty(x)),...
                defmsg)...
                ); 

            % CHART BOTTOM RIGHT TITLE
            def = sprintf("Right Hand Notes Title");
            defmsg = sprintf(['Problem with the ChartBottomRightTitle input. the ' ...
                'ChartBottomRightTitle must be a string']);
            addParameter(p,'ChartBottomRightTitle',def,...
                @(x)...
                assert(or(ischar(x),isstring(x)),...
                defmsg)...
                );

            % CHART LEFT
            def = struct();
            def.ChartType = 'Histogram';
            def.X = rand(100,1);
            def.xlab = "Data Series";
            def.ylab = "Count of Observations";
            def.ax = 'UIAxes_ChartLeft';
            defmsg = sprintf(['Problem with the ChartLeft input. the ' ...
                'ChartLeft must be a structure']);
            addParameter(p,'ChartLeft',def,...
                @(x)...
                assert(or(isstruct(x),isempty(x)),...
                defmsg)...
                );

            % CHART RIGHT
            def = struct();
            def.ChartType = 'Histogram';
            def.X = rand(100,1);
            def.xlab = "Data Series";
            def.ylab = "Count of Observations";
            def.ax = 'UIAxes_ChartLeft';
            defmsg = sprintf(['Problem with the ChartRight input. the ' ...
                'ChartRight must be a structure']);
            addParameter(p,'ChartRight',def,...
                @(x)...
                assert(or(isstruct(x),isempty(x)),...
                defmsg)...
                );

            % CHART BOTTOM RIGHT
            def = struct();
            def.ChartType = 'Histogram';
            def.X = rand(100,1);
            def.xlab = "Data Series";
            def.ylab = "Count of Observations";
            def.ax = 'UIAxes_ChartBottomRight';
            defmsg = sprintf(['Problem with the ChartBottomRight input. the ' ...
                'ChartBottomRight must be a structure']);
            addParameter(p,'ChartBottomRight',def,...
                @(x)...
                assert(or(isstruct(x),isempty(x)),...
                defmsg)...
                );            


            % Parse and set results
            parse(p,varargin{:});
            app.Label_Title.Text = p.Results.DashboardTitle;
            app.TextArea_Subtitle.Value = p.Results.DashboardSubTitle;
            app.Panel_ChartLeft.Title = p.Results.ChartLeftTitle;
            app.Panel_ChartRight.Title = p.Results.ChartRightTitle;
            app.Panel_Left.Title = p.Results.NotesLeftTitle;
            app.Panel_Right.Title = p.Results.ChartBottomRightTitle;
            DashboardFilen = p.Results.DashboardFilen;

            if ~isempty(p.Results.NotesLeftData)
                NotesLeftData = p.Results.NotesLeftData;
                leftDataFieldTitles = fieldnames(NotesLeftData.titles);
                leftDataFieldData = fieldnames(NotesLeftData.data);
                numLeftDF = min([7,length(leftDataFieldTitles),length(leftDataFieldData)]);
                for d = 1:numLeftDF
                    titfn = char(leftDataFieldTitles{d});
                    datfn = char(leftDataFieldData{d});
                    dat = NotesLeftData.data.(datfn);
                    lab = NotesLeftData.titles.(titfn);
                    textareaname = char(strcat("TextArea_left",num2str(d)));
                    textarealabname = char(strcat("Label_LeftPanelData",num2str(d)));
                    app.(textareaname).Value = dat;
                    app.(textarealabname).Text = lab;
    
                end
            end

            % Draw Charts
            if ~isempty(p.Results.ChartLeft)
                makeChart(app,p.Results.ChartLeft);
            else
                delete(app.UIAxes_ChartLeft)
            end
            if ~isempty(p.Results.ChartRight)
                makeChart(app,p.Results.ChartRight);
            else
                delete(app.UIAxes_ChartRight)
            end
            if ~ isempty(p.Results.ChartBottomRight)
                makeChart(app,p.Results.ChartBottomRight);
            else
                delete(app.UIAxes_ChartBottomRight)
            end

            % Export File
            exportapp(app.DASHBOARDUIFigure,char(strcat(DashboardFilen,".pdf")));

            delete(app)


        end

        % Callback function: not associated with a component
        function ExportToPDF(app, event)
            %
            %
            %
            %
            % -------------------------------------------------------------
            app.Button.Visible = "off";
            exportapp(app.DASHBOARDUIFigure,'dashboard.pdf')
            open('dashboard.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DASHBOARDUIFigure = uifigure('Visible', 'off');
            app.DASHBOARDUIFigure.Name = 'DASHBOARD';
            app.DASHBOARDUIFigure.Icon = fullfile(pathToMLAPP, 'AppImages', 'Icon_01.png');
            app.GridLayout = uigridlayout(app.DASHBOARDUIFigure);
            app.Label_Title = uilabel(app.GridLayout);
            app.TextArea_Subtitle = uitextarea(app.GridLayout);
            app.TextArea_Subtitle.Value = {'here is text line 1'; 'and here is text line 2, make sure both are visible'};
            app.Panel_Left = uipanel(app.GridLayout);
            app.Panel_Left.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel_Left);
            app.TextArea_left1 = uitextarea(app.GridLayout2);
            app.TextArea_left2 = uitextarea(app.GridLayout2);
            app.TextArea_left3 = uitextarea(app.GridLayout2);
            app.TextArea_left4 = uitextarea(app.GridLayout2);
            app.TextArea_left5 = uitextarea(app.GridLayout2);
            app.TextArea_left6 = uitextarea(app.GridLayout2);
            app.TextArea_left7 = uitextarea(app.GridLayout2);
            app.Label_LeftPanelData1 = uilabel(app.GridLayout2);
            app.Label_LeftPanelData1.Text = 'Label2';
            app.Label_LeftPanelData2 = uilabel(app.GridLayout2);
            app.Label_LeftPanelData2.Text = 'Label3';
            app.Label_LeftPanelData3 = uilabel(app.GridLayout2);
            app.Label_LeftPanelData3.Text = 'Label4';
            app.Label_LeftPanelData4 = uilabel(app.GridLayout2);
            app.Label_LeftPanelData4.Text = 'Label5';
            app.Label_LeftPanelData5 = uilabel(app.GridLayout2);
            app.Label_LeftPanelData5.Text = 'Label6';
            app.Label_LeftPanelData6 = uilabel(app.GridLayout2);
            app.Label_LeftPanelData6.Text = 'Label7';
            app.Label_LeftPanelData7 = uilabel(app.GridLayout2);
            app.Label_LeftPanelData7.Text = 'Label8';
            app.Panel_Right = uipanel(app.GridLayout);
            app.Panel_Right.Title = 'Panel';
            app.GridLayout2_2 = uigridlayout(app.Panel_Right);
            app.UIAxes_ChartBottomRight = uiaxes(app.GridLayout2_2);
            title(app.UIAxes_ChartBottomRight, 'Title')
            xlabel(app.UIAxes_ChartBottomRight, 'X')
            ylabel(app.UIAxes_ChartBottomRight, 'Y')
            zlabel(app.UIAxes_ChartBottomRight, 'Z')
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'AppImages', 'MAIcon.png');
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'AppImages', 'GA_Logo01.png');
            app.Panel_ChartLeft = uipanel(app.GridLayout);
            app.Panel_ChartLeft.Title = 'Panel2';
            app.GridLayout3 = uigridlayout(app.Panel_ChartLeft);
            app.UIAxes_ChartLeft = uiaxes(app.GridLayout3);
            title(app.UIAxes_ChartLeft, 'Title')
            xlabel(app.UIAxes_ChartLeft, 'X')
            ylabel(app.UIAxes_ChartLeft, 'Y')
            zlabel(app.UIAxes_ChartLeft, 'Z')
            app.Panel_ChartRight = uipanel(app.GridLayout);
            app.Panel_ChartRight.Title = 'Panel3';
            app.GridLayout4 = uigridlayout(app.Panel_ChartRight);
            app.UIAxes_ChartRight = uiaxes(app.GridLayout4);
            title(app.UIAxes_ChartRight, 'Title')
            xlabel(app.UIAxes_ChartRight, 'X')
            ylabel(app.UIAxes_ChartRight, 'Y')
            zlabel(app.UIAxes_ChartRight, 'Z')
            app.Image4 = uiimage(app.GridLayout);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'AppImages', 'Logo_02.png');
            % Show the figure after all components are created
            app.DASHBOARDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CLA_DataDashboard_01(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DASHBOARDUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DASHBOARDUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DASHBOARDUIFigure)
        end
    end
end