classdef User_Input_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        USERINPUTUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        Label_UserInstructions         matlab.ui.control.Label
        EditField_UserInputText        matlab.ui.control.EditField
        Button_Cancel                  matlab.ui.control.Button
        Button_OK                      matlab.ui.control.Button
        CLAInterpolatorUserInputLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)


    end
    
    properties (Access = public)
        STATUS  % STATS property is set to "WORKING" while the app is open 
                % and the user is working on his input.  Once the user 
                % clicks the green check mark, it will be set to "COMPLETE"
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %
            % Startup function for User Input App
            %
            %
            %
            %


            % Set STATUS to "WORKING"
            app.STATUS = "WORKING";

            % Open as a modal form
            app.USERINPUTUIFigure.WindowStyle = "alwaysontop";
            app.USERINPUTUIFigure.WindowState = "normal";
            app.USERINPUTUIFigure.Resize = "off";

            % Clear user data
            app.USERINPUTUIFigure.UserData = [];

            % The second (optional) argument is the text to include as a
            % user prompt.
            if nargin > 1
                user_prompt = varargin{1};
            else
                user_prompt = "Enter text as required.";
            end

            % Set user prompt
            app.Label_UserInstructions.Text = user_prompt;


        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            %
            % When user clicks the OK (green check mark) button
            %
            %

            % Get the entered text and store it in the user data for the
            % figure (this will be what is returned using the
            % getTextFromUser button
            enteredText = app.EditField_UserInputText.Value;
            app.USERINPUTUIFigure.UserData = enteredText;


            % Set STATUS to complete to return the data to the calling
            % function.
            app.STATUS = "COMPLETE";


        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            
        end

        % Close request function: USERINPUTUIFigure
        function USERINPUTUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.USERINPUTUIFigure = uifigure('Visible', 'off');
            app.USERINPUTUIFigure.Name = 'USER INPUT';
            app.USERINPUTUIFigure.Icon = fullfile(pathToMLAPP, 'AppImages', 'MAIcon.png');
            app.USERINPUTUIFigure.CloseRequestFcn = createCallbackFcn(app, @USERINPUTUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.USERINPUTUIFigure);
            app.CLAInterpolatorUserInputLabel = uilabel(app.GridLayout);
            app.CLAInterpolatorUserInputLabel.Text = 'CLA Interpolator User Input';
            app.Button_OK = uibutton(app.GridLayout, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Icon = fullfile(pathToMLAPP, 'AppImages', 'greenCheck.png');
            app.Button_OK.Text = '';
            app.Button_Cancel = uibutton(app.GridLayout, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Icon = fullfile(pathToMLAPP, 'AppImages', 'redX.png');
            app.Button_Cancel.Text = '';
            app.EditField_UserInputText = uieditfield(app.GridLayout, 'text');
            app.EditField_UserInputText.Placeholder = '[ENTER TEXT HERE]';
            app.Label_UserInstructions = uilabel(app.GridLayout);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AppImages', 'Icon_01.png');
            % Show the figure after all components are created
            app.USERINPUTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = User_Input_01(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.USERINPUTUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.USERINPUTUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.USERINPUTUIFigure)
        end
    end
end